classdef BTL_KTRB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        INVERSEPanel            matlab.ui.container.Panel
        Pitch_input             matlab.ui.control.NumericEditField
        Pitch_inputLabel        matlab.ui.control.Label
        a_maxEditFieldLabel     matlab.ui.control.Label
        amaxBox                 matlab.ui.control.NumericEditField
        v_maxEditFieldLabel     matlab.ui.control.Label
        vmaxBox                 matlab.ui.control.NumericEditField
        PzEditFieldLabel_2      matlab.ui.control.Label
        Pz_Inverse              matlab.ui.control.NumericEditField
        PyEditFieldLabel_2      matlab.ui.control.Label
        Py_Inverse              matlab.ui.control.NumericEditField
        Px_Inverse              matlab.ui.control.NumericEditField
        PxEditFieldLabel_2      matlab.ui.control.Label
        PlanningButton          matlab.ui.control.Button
        InverseButton           matlab.ui.control.Button
        OUTPUTPanel             matlab.ui.container.Panel
        WorkSpaceCheckBox       matlab.ui.control.CheckBox
        CoordinateaxesCheckBox  matlab.ui.control.CheckBox
        Yaw_Angle               matlab.ui.control.NumericEditField
        YawEditFieldLabel       matlab.ui.control.Label
        Roll_Angle              matlab.ui.control.NumericEditField
        RollEditFieldLabel      matlab.ui.control.Label
        Pitch_Angle             matlab.ui.control.NumericEditField
        PitchEditFieldLabel     matlab.ui.control.Label
        Z_EndEffector           matlab.ui.control.NumericEditField
        PzEditFieldLabel        matlab.ui.control.Label
        Y_EndEffector           matlab.ui.control.NumericEditField
        PyEditFieldLabel        matlab.ui.control.Label
        X_EndEffector           matlab.ui.control.NumericEditField
        PxEditFieldLabel        matlab.ui.control.Label
        INPUTPanel              matlab.ui.container.Panel
        ClearButton             matlab.ui.control.StateButton
        Theta4_Box_Forward      matlab.ui.control.NumericEditField
        Theta3_Box_Forward      matlab.ui.control.NumericEditField
        Theta2_Box_Forward      matlab.ui.control.NumericEditField
        Theta1_Box_Forward      matlab.ui.control.NumericEditField
        ResetButton             matlab.ui.control.Button
        ForwardButton           matlab.ui.control.Button
        theta2Slider            matlab.ui.control.Slider
        theta2SliderLabel       matlab.ui.control.Label
        theta4Slider            matlab.ui.control.Slider
        theta4SliderLabel       matlab.ui.control.Label
        theta3Slider            matlab.ui.control.Slider
        theta3SliderLabel       matlab.ui.control.Label
        theta1Slider            matlab.ui.control.Slider
        theta1SliderLabel       matlab.ui.control.Label
        UIAxesVel4              matlab.ui.control.UIAxes
        UIAxesVel3              matlab.ui.control.UIAxes
        UIAxesVel2              matlab.ui.control.UIAxes
        UIAxesVel1              matlab.ui.control.UIAxes
        UIAxesTheta4            matlab.ui.control.UIAxes
        UIAxesTheta3            matlab.ui.control.UIAxes
        UIAxesTheta2            matlab.ui.control.UIAxes
        UIAxesTheta1            matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            % Khai báo các ma trận chuyển đổi   
        T01 = zeros(4);             % Khớp 0 đến khớp 1
        T1a = zeros(4);             % Khớp 1 đến khớp a
        Ta2 = zeros(4);             % Khớp a đến khớp 2
        T23 = zeros(4);             % Khớp 2 đến khớp 3
        T34 = zeros(4);             % Khớp 3 đến khớp 4
        T0a = zeros(4);             % Khớp 0 đến khớp a
        T02 = zeros(4);             % Khớp 0 đến khớp 2
        T03 = zeros(4);             % Khớp 0 đến khớp 3
        T04 = zeros(4);             % Khớp 0 đến khớp 4
            % Tham số D-H (Denavit-Hartenberg) của các khớp

        theta = [   0     pi/2     -pi/2    0   0];
        d     = [   77    0     0    0     0];
        a     = [   0     128   24   124  126];
        alpha = [   pi/2   0    0   0   0];

        % d1 = 77; 
        % a2 = 130;
        % a3 = 124;
        % a4 = 126;
        % 
        % -------- Góc quay của các khớp --------
        old_theta = [0, 0, 0, 0];           % Góc quay trước đó của các khớp
        new_theta = [0, 0, 0, 0];             % Góc quay hiện tại của các khớp
        theta_offset_deg = [0, 90, -90, 0];   % offset giữa GUI và góc thực (độ)
        % theta1 = 0; 
        % theta2 = 0; 
        % theta3 = 0;
        % theta4 = 0;
        % Ma trận xoay theo trục y 
        Rotate_Y = [cos(pi/2)       0       sin(pi/2)       0   ; 
                    0               1       0               0   ; 
                    -sin(pi/2)      0       cos(pi/2)       0   ; 
                    0               0       0               1  ];
        

    % Biến vẽ đường đi 
    robotPath;
    % Biến lưu trữ kết quả các góc Euler Roll Pitch Yaw (deg)
    eulerAngles = []; 


    % -------- Các trục và nhãn cho (base), khớp và end-effector --------
    % Trục tọa độ và label của base             % Trục tọa độ và label của khớp 1
    base_X;             base_Xlabel;            joint1_X;           joint1_Xlabel; 
    base_Y;             base_Ylabel;            joint1_Y;           joint1_Ylabel;
    base_Z;             base_Zlabel;            joint1_Z;           joint1_Zlabel;  

    % Truc toa do va lable cua khop a
        
    jointa_X;           jointa_Xlabel;          
    jointa_Y;           jointa_Ylabel;         
    jointa_Z;           jointa_Zlabel;  

         
    % Trục tọa độ và label của khớp 2           
    joint2_X;           joint2_Xlabel;          
    joint2_Y;           joint2_Ylabel;          
    joint2_Z;           joint2_Zlabel;          
    % Truc toa do va lable cua khop 3
        
    joint3_X;           joint3_Xlabel;          
    joint3_Y;           joint3_Ylabel;         
    joint3_Z;           joint3_Zlabel;  

    % Truc toa do va lable cua khop ee
        
    joint4_X;           joint4_Xlabel;          
    joint4_Y;           joint4_Ylabel;         
    joint4_Z;           joint4_Zlabel;

    % Joint1Angle;Joint2Angle;Joint3Angle

    % Dùng cho inverse kinematics
    Px = 0;                     
    Py = 0;                     
    Pz = 0;  
    Pitch = 0;
    % Thông tin end-effector
    ee;

    % Trajectory Planning (LSBP)
    q1 = [];    q2 = [];    q3 = [];  q4 = [];   % Quãng đường trong Trajectory planning
    v1 = [];    v2 = [];    v3 = [];  v4 = [];   % Vận tốc trong Trajectory planning
    a1 = [];    a2 = [];    a3 = [];  a4 = [];  % Gia tốc trong Trajectory planning
    thoigian = [];
    vmax = [];      vmax1 = [];     vmax2 = [];     vmax3 = []; vmax4 = [] ; 
    amax = [];


    end
    
    methods (Access = private)
        
        function T = DH_Matrix(app, n)
                 T =       [cos(app.theta(n)),  -cos(app.alpha(n))*sin(app.theta(n)),  sin(app.alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                           sin(app.theta(n)),   cos(app.alpha(n))*cos(app.theta(n)), -sin(app.alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                           0,                   sin(app.alpha(n)),                    cos(app.alpha(n)),                   app.d(n);
                           0,                   0,                                    0,                                   1                        ];
        end
        
        function Draw_Robot(app)
        % Xóa hình ảnh robot cũ trên trục tọa độ
                hold(app.UIAxes, "off");
        
        % Các ma trận biến đổi Denavit-Hartenberg
                app.T01 = DH_Matrix(app,1);
                app.T1a = DH_Matrix(app,2);
                app.Ta2 = DH_Matrix(app,3);
                app.T23 = DH_Matrix(app,4);
                app.T34 = DH_Matrix(app,5);
               
                app.T0a = app.T01*app.T1a;
                app.T02 = app.T0a*app.Ta2;
                app.T03 = app.T02*app.T23;
                app.T04 = app.T03*app.T34;


        %% Base
        [X, Y, Z] = cylinder(30, 50); %
        Z = Z * 40;                  % 
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.00 0.45 0.74]); % Base
        hold(app.UIAxes, "on");
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.00 0.45 0.74]); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.00 0.45 0.74]); % Mặt trên
        % Trục khớp 1 

        [X, Y, Z] = cylinder(10, 50);
        Z = Z * 77;
        surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); % Mặt đáy
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); % Mặt trên

        L = 60;   % Chiều dài (trục X)
        W = 80;   % Chiều rộng (trục Y)
        H = 40;   % Chiều cao (trục Z)

        x1 = -L; x2 = L/2;
        y1 = -W/2; y2 = W/2;
        z1 = 0;    z2 = H;

        % Tạo lưới cho 4 mặt bên
        [X1, Z1] = meshgrid([x1 x2], [z1 z2]);
        Y1 = y1 * ones(size(X1));   % Mặt trước (Y = y1)

        [X2, Z2] = meshgrid([x1 x2], [z1 z2]);
        Y2 = y2 * ones(size(X2));   % Mặt sau (Y = y2)
        
        [Y3, Z3] = meshgrid([y1 y2], [z1 z2]);
        X3 = x1 * ones(size(Y3));   % Mặt trái (X = x1)
        
        [Y4, Z4] = meshgrid([y1 y2], [z1 z2]);
        X4 = x2 * ones(size(Y4));   % Mặt phải (X = x2)
        
        % Vẽ các mặt
        surf(app.UIAxes, X1, Y1, Z1, 'FaceColor', [0.00 0.45 0.74]); hold(app.UIAxes, 'on');
        surf(app.UIAxes, X2, Y2, Z2, 'FaceColor', [0.00 0.45 0.74]);
        surf(app.UIAxes, X3, Y3, Z3, 'FaceColor', [0.00 0.45 0.74]);
        surf(app.UIAxes, X4, Y4, Z4, 'FaceColor', [0.00 0.45 0.74]);
        
        % Đáy và nắp
        fill3(app.UIAxes, [x1 x2 x2 x1], [y1 y1 y2 y2], [z1 z1 z1 z1], [0.00 0.45 0.74]);
        fill3(app.UIAxes, [x1 x2 x2 x1], [y1 y1 y2 y2], [z2 z2 z2 z2], [0.00 0.45 0.74]);

        %% --------------- Vẽ Joint 1 --------------- khoi nằm ngang đầu tiên 
        % Tạo hình trụ cho Joint 1
        [X, Y, Z] = cylinder(20, 100); % Bán kính 20, 100 phân đoạn
        Z = Z * 50 - 25;               % Chiều cao và dịch trục Z

        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))];% tạo ma trân biểu diễn các điểm ở mặt trên
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];% tạo ma trân biểu diễn các điểm ở mặt dưới 
        A_new = app.T01 * A; B_new = app.T01 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
        % 
        % % Vẽ Joint 1
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.50,0.50,0.50]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.50,0.50,0.50]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.50,0.50,0.50]); % Mặt đáy trên
         %% --------------- Vẽ Joint 2 --------------- 

        L = 40;   % Chiều dài (trục X)
        W = 40;   % Chiều rộng (trục Y)
        H = 40;   % Chiều cao (trục Z)

       % Tạo 8 đỉnh của khối hộp (tâm tại gốc, dọc theo trục Z)
        x = [-H/2 H/2 H/2 -H/2 -H/2 H/2 H/2 -H/2];
        y = [-W/2 -W/2 W/2 W/2 -W/2 -W/2 W/2 W/2];
        z = [-L/2 -L/2 -L/2 -L/2  L/2  L/2  L/2  L/2];
        
        % Mỗi cột là 1 điểm trong không gian đồng nhất
        P = [x; y; z; ones(1,8)];
        
        % Biến đổi DH (Denavit-Hartenberg)
        P_new = app.T0a * P;
        
        % Lấy lại tọa độ sau biến đổi
        x_new = P_new(1, :);
        y_new = P_new(2, :);
        z_new = P_new(3, :);
        
        % Định nghĩa 6 mặt của khối hộp (theo thứ tự 4 đỉnh/mặt)
        faces = [1 2 3 4;  % mặt sau
                  5 6 7 8;  % mặt trước
                  1 2 6 5;  % mặt dưới
                  2 3 7 6;  % mặt phải
                  3 4 8 7;  % mặt trên
                  4 1 5 8]; % mặt trái
        
        % Vẽ khối hộp (Joint 1)
        patch(app.UIAxes, ...
            'Vertices', [x_new' y_new' z_new'], ...
            'Faces', faces, ...
            'FaceColor', [0.00,0.45,0.74], ...
            'EdgeColor', 'black');  % tắt viền để giống cylinder
        
        % Tạo hình trụ cho Joint 2
        [X, Y, Z] = cylinder(20, 40); % Bán kính 20, 40 phân đoạn
        Z = Z * 50 - 25;               % Chiều cao và dịch trục Z

        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T02 * A; B_new = app.T02 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

        % Vẽ Joint 2
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.93,0.69,0.13]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.93,0.69,0.13]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.93,0.69,0.13]); % Mặt đáy trên
        %% --------------- Vẽ Joint 3 --------------- 
        % Tạo hình trụ cho Joint 3
        [X, Y, Z] = cylinder(20, 40); % Bán kính 20, 100 phân đoạn
        Z = Z * 50 - 25;               % Chiều cao và dịch trục Z

        % Biến đổi DH và cập nhật tọa độ
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.T03 * A; B_new = app.T03 * B;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

        % Vẽ Joint 3
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.00,0.45,0.74]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.80,0.80,0.80]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.80,0.80,0.80]); % Mặt đáy trên
        %% --------------- Vẽ End-effector --------------- 
     % Tạo end-effector
    x_e = [ 5,  50,   40,   30,   20,   -20,  -30,   -20,   20,   30,   40,   50,   5];
    z_e = [-10,  -10,  -20,  -20,  -30,  -40,    0,   40,   30,   20,   20,   10,   10];
    
    y_e = [  10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10 ; ...
        -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10];


    x_e = repmat(x_e, 2, 1); % Tạo ma trận 2 hàng từ x_e
    z_e = repmat(z_e, 2, 1); % Tạo ma trận 2 hàng từ z_e
    
    % Biến đổi DH cho effector
        A = [x_e(1, :); y_e(1, :); z_e(1, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt dưới theo vecto cột, 
        B = [x_e(2, :); y_e(2, :); z_e(2, :); ones(1, size(x_e, 2))];   % Lưu các điểm mặt trên theo vecto cột,
        A_new = app.T04 * A;        % Nhân các điểm của ma trận A với T04
        B_new = app.T04 * B;        % Nhân các điểm của ma trận B với T04
    
        x_e(1, :) = A_new(1, :);        y_e(1, :) = A_new(2, :);        % Cập nhật lại các tọa độ x y z
        x_e(2, :) = B_new(1, :);        y_e(2, :) = B_new(2, :);
        z_e(1, :) = A_new(3, :);        z_e(2, :) = B_new(3, :);
    
    % Vẽ effector
    surf(app.UIAxes, x_e, y_e, z_e, 'FaceColor', [0.93,0.69,0.13]);
    fill3(app.UIAxes, x_e(1, :), y_e(1, :), z_e(1, :), [0.00,0.45,0.74]); % Mặt trên
    fill3(app.UIAxes, x_e(2, :), y_e(2, :), z_e(2, :), [0.00,0.45,0.74]); % Mặt dưới

    x_endEffector = 50;
    y_endEffector = 0;
    z_endEffector = 0;
   
    P_endEffector = [x_endEffector; y_endEffector; z_endEffector; 1];            % Tọa độ điểm P của end effector

    P_endEffector = app.T04 * P_endEffector;                   % Nhân các điểm của ma trận P với T04
    
    x_endEffector = P_endEffector(1, 1);                       % Cập nhật lại các tọa độ x y z
    y_endEffector = P_endEffector(2, 1);
    z_endEffector = P_endEffector(3, 1);
   
    
    app.X_EndEffector.Value = x_endEffector;                % Hiển thị vị trí của effector lên box value
    app.Y_EndEffector.Value = y_endEffector;
    app.Z_EndEffector.Value = z_endEffector;

    % Thêm tọa độ mới vào đường đi
    [~, n] = size(app.robotPath);
    app.robotPath(1, n + 1) = x_endEffector;   % ban đầu khai báo biến robotPath, và biến n là số cột của biến robotPath
    app.robotPath(2, n + 1) = y_endEffector;   % cập nhật các giá trị x y z của end effector, đồng thời tăng số cột lên 1  
    app.robotPath(3, n + 1) = z_endEffector;   % để tạo cột cho các giá trị x y z tiếp theo    
   % Vẽ đường đi trên trục tọa độ UIAxes
    plot3(app.UIAxes, app.robotPath(1, :), app.robotPath(2, :), app.robotPath(3, :), 'LineWidth', 2, 'Color', 'b');
    %% ====== Vẽ workspace ======
    if isprop(app,'WorkSpaceCheckBox') && app.WorkSpaceCheckBox.Value
    
        % Bán kính vùng làm việc (xấp xỉ tầm với max ≈ 0.4 m)
        r = 380;            % mm  (có thể chỉnh 380–420 nếu muốn to/nhỏ hơn)

        % Tâm theo trục Z đặt gần khớp 2 (d1 = 77, link đứng = 128 -> 77+128=205)
        z_center = 205;     % mm

        % Lưới mặt cầu
        [u, v] = meshgrid(linspace(0, 2*pi, 40), linspace(0, pi, 40));
        x = r .* cos(u) .* sin(v);
        y = r .* sin(u) .* sin(v);
        z = r .* cos(v) + z_center;

        hold(app.UIAxes, "on");
        surf(app.UIAxes, x, y, z, ...
            'EdgeColor', 'none', ...
            'FaceColor', [0.5 0.5 0.5], ...
            'FaceAlpha', 0.15);
    end
   % Tính toán góc Euler từ ma trận biến đổi T03 (3 góc quay theo trục x y z: roll ψ, pitch θ, yaw ϕ)

    
    %---------------------------------------------------------------------
        %       [  cϕ * cθ     cϕ * sθ * sψ - sϕ * cψ      cϕ * sθ * cψ + sϕ * sψ        -   ]
        % T04 = |  sϕ * cθ     sϕ * sθ * sψ + cϕ * cψ      sϕ * sθ * cψ - cϕ * sψ        -   |
        %       |  -sθ         cθ * sψ                     cθ * cψ                       -   |
        %       [    0             0                         0                           1   ]
    %---------------------------------------------------------------------
        
    cospitch = sqrt((app.T04(1, 1))^2 + (app.T04(2, 1))^2);     %cθ = sqrt[ (cϕ * cθ)^2 + (sϕ * cθ)^2 ]
    pitch = atan2(-app.T04(3, 1), cospitch);                    %sθ = -T04(3, 1)]


    %---------------------------------------------------------------------


    if pitch == pi / 2          % Trường hợp robot duỗi thẳng lên trên, vô số nghiệm yaw, chọn yaw = 0

        yaw = 0;                                                %sψ = r12
        roll = atan2(app.T04(1, 2), app.T04(2, 2));             %cψ = r22

%---------------------------------------------------------------------
        % Tại pitch = -pi/2 thì
        %       [  0     -s(ψ - ϕ)     -c(ψ - ϕ)        -   ]
        % T04 = |  0      c(ψ - ϕ)     -s(ψ - ϕ)        -   |
        %       |  1        0             0             -   |
        %       [  0        0             0             1   ]
%---------------------------------------------------------------------

    elseif pitch == -pi / 2

        yaw = 0;                                                %sψ = -r12
        roll = atan2(-app.T04(1, 2), app.T04(2, 2));            %cψ =  r22

    else
                                                                                %sϕ = r21/cθ
        yaw = atan2(app.T04(2, 1) / cos(pitch), app.T04(1, 1) / cos(pitch));    %cϕ = r11/cθ
        roll = atan2(app.T04(3, 2) / cos(pitch), app.T04(3, 3) / cos(pitch));   %sψ = r32/cθ
                                                                                %cψ = r33/cθ
    end
    app.eulerAngles = [roll pitch yaw] * (180 / pi); % Chuyển đổi từ radian sang độ

    % Cập nhật giá trị góc Euler vào các ô nhập liệu
    app.Roll_Angle.Value    = app.eulerAngles(1);
    app.Pitch_Angle.Value   = app.eulerAngles(2);
    app.Yaw_Angle.Value     = app.eulerAngles(3);  
    % hien thi trang thai coordinateaxes
    app.CoordinateaxesCheckBoxValueChanged();
     %% --------------- Vẽ Link 1 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10,10);  % Bán kính 10
        Z = Z * 128 - 128;           % Chiều cao và dịch trục Z

        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T0a * A_new; B_new = app.T0a * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

        
        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.80,0.80,0.80]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.80,0.80,0.80]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.80,0.80,0.80]); % Mặt đáy trên

                %% --------------- Vẽ Link 2 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10,10);  % Bán kính 10
        Z = Z * 124 - 124;           % Chiều cao và dịch trục Z

        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T03 * A_new; B_new = app.T03 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);


        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.64,0.08,0.18]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.64,0.08,0.18]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.64,0.08,0.18]); % Mặt đáy trên
              %% --------------- Vẽ Link 3 --------------- 
        % Tạo hình trụ cho Link 1
        [X, Y, Z] = cylinder(10,10);  % Bán kính 10
        Z = Z * 126-126 ;           % Chiều cao và dịch trục Z

        % Xoay quanh trục Y và biến đổi DH
        A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
        B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
        A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
        A_new = app.T04 * A_new; B_new = app.T04 * B_new;
        X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
        Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
        Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);


        surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.80,0.80,0.80]);
        fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.93,0.69,0.13]); % Mặt đáy dưới
        fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.93,0.69,0.13]); % Mặt đáy trên
        end
        
        function LSPB_TrajectoryPlanning(app)
        % 1. Khởi tạo biến cho 4 khớp
            app.q1 = []; app.q2 = []; app.q3 = []; app.q4 = [];
            app.v1 = []; app.v2 = []; app.v3 = []; app.v4 = [];
            app.a1 = []; app.a2 = []; app.a3 = []; app.a4 = [];
            app.thoigian = [];
            
            app.vmax1 = app.vmax; app.vmax2 = app.vmax; 
            app.vmax3 = app.vmax; app.vmax4 = app.vmax; % Thêm Vmax4

    %% --- PHẦN 1: HOẠCH ĐỊNH THỜI GIAN (PLANNING) ---

    % >>> Joint 1
    qmax1 = abs(app.new_theta(1)-app.old_theta(1));
    if (qmax1 ~=0)
        if app.vmax > sqrt(qmax1*app.amax)
           app.vmax1 = sqrt(qmax1*app.amax);
        end
        t_TangToc1 = app.vmax1/app.amax;
        q_TangToc1 = 1/2 * (app.amax) * (t_TangToc1^2);
        t_Deu1 = (qmax1-2*q_TangToc1)/app.vmax1;
        t_Tong1 = 2*t_TangToc1+t_Deu1;
    else
        t_TangToc1 = 0; t_Deu1 = 0; t_Tong1 = 0;
    end

    % >>> Joint 2
    qmax2 = abs(app.new_theta(2)-app.old_theta(2));
    if (qmax2 ~=0)
        if app.vmax > sqrt(qmax2*app.amax)
           app.vmax2 = sqrt(qmax2*app.amax);
        end
        t_TangToc2 = app.vmax2/app.amax;
        q_TangToc2 = 1/2*app.amax*t_TangToc2^2;
        t_Deu2 = (qmax2-2*q_TangToc2)/app.vmax2;
        t_Tong2 = 2*t_TangToc2+t_Deu2;
    else
        t_Tong2 = 0; t_TangToc2 = 0; t_Deu2 = 0;
    end

    % >>> Joint 3
    qmax3 = abs(app.new_theta(3)-app.old_theta(3));
    if(qmax3 ~=0)
        if app.vmax > sqrt(qmax3*app.amax)
           app.vmax3 = sqrt(qmax3*app.amax);
        end
        t_TangToc3 = app.vmax3/app.amax;
        q_TangToc3 = 1/2*app.amax*t_TangToc3^2;
        t_Deu3 = (qmax3-2*q_TangToc3)/app.vmax3;
        t_Tong3 = 2*t_TangToc3+t_Deu3;
    else
        t_Tong3 = 0; t_TangToc3 = 0; t_Deu3 = 0;
    end

    % >>> Joint 4 (MỚI THÊM VÀO)
    qmax4 = abs(app.new_theta(4)-app.old_theta(4));
    if(qmax4 ~=0)
        if app.vmax > sqrt(qmax4*app.amax)
           app.vmax4 = sqrt(qmax4*app.amax);
        end
        t_TangToc4 = app.vmax4/app.amax;                 % Thời gian tăng tốc joint4
        q_TangToc4 = 1/2*app.amax*t_TangToc4^2;          % Quãng đường tăng tốc joint4
        t_Deu4 = (qmax4-2*q_TangToc4)/app.vmax4;         % Thời gian đều joint4
        t_Tong4 = 2*t_TangToc4+t_Deu4;                   % Tổng thời gian joint4
    else
        t_Tong4 = 0; t_TangToc4 = 0; t_Deu4 = 0;
    end

    %% --- PHẦN 2: TẠO VECTO THỜI GIAN CHUNG ---
    % Lấy thời gian dài nhất trong 4 khớp
    T_max = max([t_Tong1 t_Tong2 t_Tong3 t_Tong4]); 
    
    t_current = zeros(size(app.thoigian));
    app.thoigian = 0:0.015:T_max;

    %% --- PHẦN 3: VÒNG LẶP TÍNH TOÁN TỪNG BƯỚC ---
    for i=1:length(app.thoigian)
        
        t_current(i) = app.thoigian(i);
        
        % >>> Tính toán Joint 1
        if(qmax1 ~= 0)
            if app.thoigian(i) < t_TangToc1
                app.q1(i) = 1/2*app.amax*app.thoigian(i)^2;     
                app.v1(i) = app.amax*app.thoigian(i);
                app.a1(i) = app.amax;
            elseif app.thoigian(i) < t_TangToc1 + t_Deu1
                app.q1(i) = q_TangToc1 + app.vmax1*(app.thoigian(i)-t_TangToc1);
                app.v1(i) = app.vmax1;
                app.a1(i) = 0;
            elseif app.thoigian(i) <= t_Tong1
                app.q1(i) = qmax1 - 1/2*app.amax*(t_Tong1-app.thoigian(i))^2;
                app.v1(i) = app.amax*(t_Tong1-app.thoigian(i));
                app.a1(i) = -app.amax;
            else
                app.q1(i) = qmax1; app.v1(i) = 0; app.a1(i) = 0;
            end
        else
            app.q1(i) = qmax1; app.v1(i) = 0; app.a1(i) = 0;
        end

        % >>> Tính toán Joint 2
        if(qmax2 ~= 0)
            if app.thoigian(i) < t_TangToc2
                app.q2(i) = 1/2*app.amax*app.thoigian(i)^2;
                app.v2(i) = app.amax*app.thoigian(i);
                app.a2(i) = app.amax;
            elseif app.thoigian(i) < t_TangToc2 + t_Deu2
                app.q2(i) = q_TangToc2 + app.vmax2*(app.thoigian(i)-t_TangToc2);
                app.v2(i) = app.vmax2;
                app.a2(i) = 0;
            elseif app.thoigian(i) <= t_Tong2
                app.q2(i) = qmax2 - 1/2*app.amax*(t_Tong2-app.thoigian(i))^2;
                app.v2(i) = app.amax*(t_Tong2-app.thoigian(i));
                app.a2(i) = -app.amax;
            else
                app.q2(i) = qmax2; app.v2(i) = 0; app.a2(i) = 0;
            end
        else
            app.q2(i) = qmax2; app.v2(i) = 0; app.a2(i) = 0;
        end

        % >>> Tính toán Joint 3
        if(qmax3 ~= 0)
            if app.thoigian(i) < t_TangToc3
                app.q3(i) = 1/2*app.amax*app.thoigian(i)^2;
                app.v3(i) = app.amax*app.thoigian(i);
                app.a3(i) = app.amax;
            elseif app.thoigian(i) < t_TangToc3 + t_Deu3
                app.q3(i) = q_TangToc3 + app.vmax3*(app.thoigian(i)-t_TangToc3);
                app.v3(i) = app.vmax3;
                app.a3(i) = 0;
            elseif app.thoigian(i) <= t_Tong3
                app.q3(i) = qmax3 - 1/2*app.amax*(t_Tong3-app.thoigian(i))^2;
                app.v3(i) = app.amax*(t_Tong3-app.thoigian(i));
                app.a3(i) = -app.amax;
            else
                app.q3(i) = qmax3; app.v3(i) = 0; app.a3(i) = 0;
            end
        else 
            app.q3(i) = qmax3; app.v3(i) = 0; app.a3(i) = 0;
        end

        % >>> Tính toán Joint 4 (MỚI THÊM VÀO)
        if(qmax4 ~= 0)
            if app.thoigian(i) < t_TangToc4
                app.q4(i) = 1/2*app.amax*app.thoigian(i)^2;
                app.v4(i) = app.amax*app.thoigian(i);
                app.a4(i) = app.amax;
            elseif app.thoigian(i) < t_TangToc4 + t_Deu4
                app.q4(i) = q_TangToc4 + app.vmax4*(app.thoigian(i)-t_TangToc4);
                app.v4(i) = app.vmax4;
                app.a4(i) = 0;
            elseif app.thoigian(i) <= t_Tong4
                app.q4(i) = qmax4 - 1/2*app.amax*(t_Tong4-app.thoigian(i))^2;
                app.v4(i) = app.amax*(t_Tong4-app.thoigian(i));
                app.a4(i) = -app.amax;
            else
                app.q4(i) = qmax4; app.v4(i) = 0; app.a4(i) = 0;
            end
        else 
            app.q4(i) = qmax4; app.v4(i) = 0; app.a4(i) = 0;
        end

        %% --- PHẦN 4: VẼ ĐỒ THỊ & UPDATE ROBOT ---
        if(mod(i,5) == 0 || i == length(app.thoigian))
            
            % Update Joint 1
            if(app.new_theta(1) - app.old_theta(1) >= 0)
                app.theta(1) = app.old_theta(1) + app.q1(i);
            else
                app.theta(1) = app.old_theta(1) - app.q1(i);
            end
            % Vẽ đồ thị 1
            if(app.new_theta(1) - app.old_theta(1) >= 0)
                plot(app.UIAxesTheta1, t_current, rad2deg(app.q1 + app.old_theta(1)));
            else
                plot(app.UIAxesTheta1, t_current, rad2deg(-app.q1 + app.old_theta(1)));
            end
            plot(app.UIAxesVel1, t_current, rad2deg(app.v1));

            % Update Joint 2
            if(app.new_theta(2) - app.old_theta(2) >= 0)
                app.theta(2) = app.old_theta(2) + app.q2(i);
            else
                app.theta(2) = app.old_theta(2) - app.q2(i);
            end
            % Vẽ đồ thị 2
            if(app.new_theta(2) - app.old_theta(2) >= 0)
                plot(app.UIAxesTheta2, t_current, rad2deg(app.q2 + app.old_theta(2)));
            else
                plot(app.UIAxesTheta2, t_current, rad2deg(-app.q2 + app.old_theta(2)));
            end
            plot(app.UIAxesVel2, t_current, rad2deg(app.v2));

            % Update Joint 3
            if(app.new_theta(3) - app.old_theta(3) >= 0)
                app.theta(3) = app.old_theta(3) + app.q3(i);
            else
                app.theta(3) = app.old_theta(3) - app.q3(i);
            end
            % Vẽ đồ thị 3
            if(app.new_theta(3) - app.old_theta(3) >= 0)
                plot(app.UIAxesTheta3, t_current, rad2deg(app.q3 + app.old_theta(3)));
            else
                plot(app.UIAxesTheta3, t_current, rad2deg(-app.q3 + app.old_theta(3)));
            end
            plot(app.UIAxesVel3, t_current, rad2deg(app.v3));

            % Update Joint 4 (MỚI THÊM VÀO)
            if(app.new_theta(4) - app.old_theta(4) >= 0)
                app.theta(5) = app.old_theta(4) + app.q4(i);
            else
                app.theta(5) = app.old_theta(4) - app.q4(i);
            end
            
            % Vẽ đồ thị 4 (Dùng Try-Catch phòng khi bạn chưa tạo trục UIAxes4)
            try
                if(app.new_theta(4) - app.old_theta(4) >= 0)
                    plot(app.UIAxesTheta4, t_current, rad2deg(app.q4 + app.old_theta(4)));
                else
                    plot(app.UIAxesTheta4, t_current, rad2deg(-app.q4 + app.old_theta(4)));
                end
                plot(app.UIAxesVel4, t_current, rad2deg(app.v4));
            catch
                % Nếu chưa có trục vẽ 4 thì bỏ qua, không báo lỗi
            end          
        end
          
    end
        
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Góc ban đầu của robot (rad): [0, 90, -90, 0]
            app.theta(1) = deg2rad(app.theta_offset_deg(1)); % 0
            app.theta(2) = deg2rad(app.theta_offset_deg(2)); % 90
            app.theta(3) = deg2rad(app.theta_offset_deg(3)); % -90
            app.theta(4) = deg2rad(app.theta_offset_deg(4)); % 0

            app.old_theta = app.theta(1:4);
            app.robotPath = [];
            
            app.theta1Slider.Value = 0;
            app.theta2Slider.Value = 0;
            app.theta3Slider.Value = 0;
            app.theta4Slider.Value = 0;

            app.Theta1_Box_Forward.Value = 0;
            app.Theta2_Box_Forward.Value = 0 ;
            app.Theta3_Box_Forward.Value = 0 ;
            app.Theta4_Box_Forward.Value = 0 ;


            app.Draw_Robot();
        end

        % Value changed function: theta1Slider
        function theta1SliderValueChanged(app, event)
                ui1 = app.theta1Slider.Value;
                app.Theta1_Box_Forward.Value = ui1;
                th1_real_deg = ui1 + app.theta_offset_deg(1);
                app.new_theta(1) = deg2rad(th1_real_deg);
                
        end

        % Value changed function: theta2Slider
        function theta2SliderValueChanged(app, event)
                ui2 = app.theta2Slider.Value;
                app.Theta2_Box_Forward.Value = ui2;
                th2_real_deg = ui2 + app.theta_offset_deg(2);
                app.new_theta(2) = deg2rad(th2_real_deg);       
        end

        % Value changed function: theta3Slider
        function theta3SliderValueChanged(app, event)
                ui3 = app.theta3Slider.Value;
                app.Theta3_Box_Forward.Value = ui3;
                th3_real_deg = ui3 + app.theta_offset_deg(3);
                app.new_theta(3) = deg2rad(th3_real_deg);
        end

        % Value changed function: theta4Slider
        function theta4SliderValueChanged(app, event)
                ui4 = app.theta4Slider.Value;
                app.Theta4_Box_Forward.Value = ui4;
                th4_real_deg = ui4 + app.theta_offset_deg(4);
                app.new_theta(4) = deg2rad(th4_real_deg);              
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)

            % Lưu góc thực hiện tại
            app.old_theta = app.theta(1:4);


            % Joint 1
            ui1 = app.theta1Slider.Value;          % deg hiển thị
            app.Theta1_Box_Forward.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);

            % Joint 2
            ui2 = app.theta2Slider.Value;
            app.Theta2_Box_Forward.Value = ui2;
            th2_real_deg = ui2 + app.theta_offset_deg(2);
            app.new_theta(2) = deg2rad(th2_real_deg);

            % Joint 3
            ui3 = app.theta3Slider.Value;
            app.Theta3_Box_Forward.Value = ui3;
            th3_real_deg = ui3 + app.theta_offset_deg(3);
            app.new_theta(3) = deg2rad(th3_real_deg);

            % Joint 4
            ui4 = app.theta4Slider.Value;
            app.Theta4_Box_Forward.Value = ui4;
            th4_real_deg = ui4 + app.theta_offset_deg(4);
            app.new_theta(4) = deg2rad(th4_real_deg);


            %% ================== Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau =======================
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                drawnow limitrate;
                %pause(0.05);
            end
                
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           % Lưu góc thực hiện tại
            app.old_theta = app.theta(1:4);

            % Mục tiêu: về tư thế home THỰC = offset (deg)
            for j = 1:4
                ui = 0; % GUI luôn về 0
                real_deg = ui + app.theta_offset_deg(j);  % = offset
                app.new_theta(j) = deg2rad(real_deg);

                switch j
                    case 1
                        app.theta1Slider.Value = ui;
                        app.Theta1_Box_Forward.Value = ui;
                    case 2
                        app.theta2Slider.Value = ui;
                        app.Theta2_Box_Forward.Value = ui;
                    case 3
                        app.theta3Slider.Value = ui;
                        app.Theta3_Box_Forward.Value = ui;
                    case 4
                        app.theta4Slider.Value = ui;
                        app.Theta4_Box_Forward.Value = ui;
                end
            end

 %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                drawnow limitrate;
            end
              
            
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(app, event)
            app.robotPath = [];
            app.Draw_Robot();
            
        end

        % Value changed function: Px_Inverse
        function Px_InverseValueChanged(app, event)
            app.Px = app.Px_Inverse.Value;
        end

        % Value changed function: Py_Inverse
        function Py_InverseValueChanged(app, event)
            app.Py = app.Py_Inverse.Value;            
        end

        % Value changed function: Pz_Inverse
        function Pz_InverseValueChanged(app, event)
            app.Pz = app.Pz_Inverse.Value - app.d(1);             
        end

        % Value changed function: CoordinateaxesCheckBox
        function CoordinateaxesCheckBoxValueChanged(app, event)
            value = app.CoordinateaxesCheckBox.Value;
            if value 
            % Vẽ các trục tọa độ x y z cho base
                x_axis = [100 0 0 1]'; % Trục X
                y_axis = [0 100 0 1]'; % Trục Y
                z_axis = [0 0 100 1]'; % Trục Z
                origin = [0 0 0 1]'; % Gốc tọa độ
            % Tính tọa độ mới của các trục sau khi chuyển đổi
                new_x_axis = x_axis;
                new_y_axis = y_axis;
                new_z_axis = z_axis;
                new_origin = origin;
            % Vẽ các trục đã chuyển đổi và lưu đối tượng để xóa sau này
                app.base_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.base_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.base_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
            % Gắn các nhãn
                app.base_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.base_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.base_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');

             % Vẽ các trục cho joint1
                x_axis = [100 0 0 1]'; 
                y_axis = [0 100 0 1]'; 
                z_axis = [0 0 100 1]'; 
                origin = [0 0 0 1]'; 
             
                new_x_axis = app.T01 * x_axis;
                new_y_axis = app.T01 * y_axis;
                new_z_axis = app.T01 * z_axis;
                new_origin = app.T01 * origin;
                    
                app.joint1_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.joint1_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.joint1_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
                app.joint1_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint1_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint1_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
                

                 % Vẽ các trục cho jointa
                x_axis = [100 0 0 1]'; 
                y_axis = [0 100 0 1]'; 
                z_axis = [0 0 100 1]'; 
                origin = [0 0 0 1]'; 
                
                new_x_axis = app.T0a * x_axis;
                new_y_axis = app.T0a * y_axis;
                new_z_axis = app.T0a * z_axis;
                new_origin = app.T0a * origin;
            
                app.jointa_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.jointa_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.jointa_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
                app.jointa_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.jointa_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.jointa_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');

                 % Vẽ các trục cho joint2
                x_axis = [100 0 0 1]'; 
                y_axis = [0 100 0 1]'; 
                z_axis = [0 0 100 1]'; 
                origin = [0 0 0 1]'; 
                
                new_x_axis = app.T02 * x_axis;
                new_y_axis = app.T02 * y_axis;
                new_z_axis = app.T02 * z_axis;
                new_origin = app.T02 * origin;
            
                app.joint2_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.joint2_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.joint2_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
                app.joint2_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint2_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint2_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');

                 % Vẽ các trục cho joint3
                x_axis = [100 0 0 1]'; 
                y_axis = [0 100 0 1]'; 
                z_axis = [0 0 100 1]'; 
                origin = [0 0 0 1]'; 
                
                new_x_axis = app.T03 * x_axis;
                new_y_axis = app.T03 * y_axis;
                new_z_axis = app.T03 * z_axis;
                new_origin = app.T03 * origin;
            
                app.joint3_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.joint3_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.joint3_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
                app.joint3_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint3_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint3_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');

                 % Vẽ các trục cho joint endeffector 
                x_axis = [100 0 0 1]'; 
                y_axis = [0 100 0 1]'; 
                z_axis = [0 0 100 1]'; 
                origin = [0 0 0 1]'; 
                
                new_x_axis = app.T04 * x_axis;
                new_y_axis = app.T04 * y_axis;
                new_z_axis = app.T04 * z_axis;
                new_origin = app.T04 * origin;
            
                app.joint4_X = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
                app.joint4_Y = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
                app.joint4_Z = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
                app.joint4_Xlabel = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint4_Ylabel = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint4_Zlabel = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
            else 

                % Xóa các trục và nhãn đã vẽ nếu checkbox không được chọn
    
                    delete(app.base_X);
                    delete(app.base_Y);
                    delete(app.base_Z);
                    delete(app.base_Xlabel);
                    delete(app.base_Ylabel);
                    delete(app.base_Zlabel);
    
                    delete(app.joint1_X);
                    delete(app.joint1_Y);
                    delete(app.joint1_Z);
                    delete(app.joint1_Xlabel);
                    delete(app.joint1_Ylabel);
                    delete(app.joint1_Zlabel);
             
                    delete(app.jointa_X);
                    delete(app.jointa_Y);
                    delete(app.jointa_Z);
                    delete(app.jointa_Xlabel);
                    delete(app.jointa_Ylabel);
                    delete(app.jointa_Zlabel);

                    delete(app.joint2_X);
                    delete(app.joint2_Y);
                    delete(app.joint2_Z);
                    delete(app.joint2_Xlabel);
                    delete(app.joint2_Ylabel);
                    delete(app.joint2_Zlabel);

                    delete(app.joint3_X);
                    delete(app.joint3_Y);
                    delete(app.joint3_Z);
                    delete(app.joint3_Xlabel);
                    delete(app.joint3_Ylabel);
                    delete(app.joint3_Zlabel);

                    delete(app.joint4_X);
                    delete(app.joint4_Y);
                    delete(app.joint4_Z);
                    delete(app.joint4_Xlabel);
                    delete(app.joint4_Ylabel);
                    delete(app.joint4_Zlabel);
         
                   
            end
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
%% ===== 1. CẤU HÌNH THAM SỐ ROBOT =====
    d1 = 77;                % Base Height
    L2 = 128;               % Link 2
    L3 = 24 + 124;          % Link 3 = 148mm
    L4 = 126 + 50;          % Link 4 = 176mm
    
    offsets = [0, 90, -90, 0]; 

    %% ===== 2. ĐỌC DỮ LIỆU =====
    px = app.Px_Inverse.Value;
    py = app.Py_Inverse.Value;
    pz = app.Pz_Inverse.Value;
    pitch_val = app.Pitch_input.Value;

    % Input Pitch -90 (thẳng đứng) -> Tính toán +90
    phi = deg2rad(-pitch_val);

    %% ===== 3. XỬ LÝ GÓC ĐẾ & BÁN KÍNH (LOGIC MỚI) =====
    
    % Tính góc đế thô
    theta1_raw = atan2(py, px);
    
    % Tính khoảng cách phẳng (Radius)
    pr = sqrt(px^2 + py^2);

    % --- LOGIC ƯU TIÊN GIỮ ĐẾ KHÔNG XOAY (BACKWARD REACH) ---
    % Nếu điểm đến nằm phía sau (góc đế > 90 hoặc < -90)
    % Thay vì quay đế 180 độ, ta giữ đế ở 0 và cho bán kính âm
    if abs(theta1_raw) > pi/2
        % Ép góc đế về phía trước (Xoay ngược 180 độ)
        % Ví dụ: đang là 180 -> thành 0
        if theta1_raw > 0
            theta1 = theta1_raw - pi;
        else
            theta1 = theta1_raw + pi;
        end
        
        % ĐẢO DẤU BÁN KÍNH (Quan trọng nhất)
        % Khi r âm, các công thức sau sẽ tự động hiểu là vươn ra sau
        pr = -pr; 
    else
        theta1 = theta1_raw;
    end

    %% ===== 4. TÍNH TOÁN IK =====

    % Tọa độ End-Effector so với khớp 2
    % Lưu ý: pr ở đây có thể là số ÂM (ví dụ -128)
    r_ee = pr;      
    z_ee = pz - d1;

    % Tọa độ Wrist Center (J4)
    r_wc = r_ee - L4 * cos(phi);
    z_wc = z_ee - L4 * sin(phi);

    % --- Tính Theta 3 ---
    numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
    denominator = 2 * L2 * L3;
    D = numerator / denominator;

    % Kẹp giá trị D
    if D > 1, D = 1; elseif D < -1, D = -1; end
    
    % Chọn Elbow Up (Dấu -)
    theta3_geom = -acos(D); 

    % --- Tính Theta 2 ---
    k1 = L2 + L3 * cos(theta3_geom);
    k2 = L3 * sin(theta3_geom);
    
    % Hàm atan2 xử lý rất tốt số âm: 
    % Nếu r_wc âm, nó sẽ tính ra góc tù (>90 độ) -> Robot ngả ra sau
    theta2_geom = atan2(z_wc, r_wc) - atan2(k2, k1);

    % --- Tính Theta 4 ---
    theta4_geom = phi - theta2_geom - theta3_geom;

    %% ===== 5. CẬP NHẬT KẾT QUẢ =====
    
    % Cập nhật biến vẽ
    app.theta(1) = theta1;
    app.theta(2) = theta2_geom;
    app.theta(3) = theta3_geom;
    app.theta(5) = theta4_geom;

    % Cập nhật Slider (Giá trị - Offset)
    app.Theta1_Box_Forward.Value = rad2deg(theta1) - offsets(1);
    app.theta1Slider.Value       = rad2deg(theta1) - offsets(1);

    app.Theta2_Box_Forward.Value = rad2deg(theta2_geom) - offsets(2);
    app.theta2Slider.Value       = rad2deg(theta2_geom) - offsets(2);

    app.Theta3_Box_Forward.Value = rad2deg(theta3_geom) - offsets(3);
    app.theta3Slider.Value       = rad2deg(theta3_geom) - offsets(3);

    app.Theta4_Box_Forward.Value = rad2deg(theta4_geom) - offsets(4);
    app.theta4Slider.Value       = rad2deg(theta4_geom) - offsets(4);

    % Vẽ lại robot
    app.old_theta(1) = app.new_theta(1);
    app.new_theta(1) = app.theta(1);
    app.old_theta(2) = app.new_theta(2);
    app.new_theta(2) = app.theta(2);
    app.old_theta(3) = app.new_theta(3);
    app.new_theta(3) = app.theta(3);
    app.old_theta(4) = app.new_theta(4);
    app.new_theta(4) = app.theta(5);

    % ===== Lưu trạng thái cũ và mới của các góc ====
     %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                drawnow limitrate;
            end
           
        end

        % Value changed function: Theta1_Box_Forward
        function Theta1_Box_ForwardValueChanged(app, event)
            ui1 = app.Theta1_Box_Forward.Value;
            app.theta1Slider.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);       
        end

        % Value changed function: Theta2_Box_Forward
        function Theta2_Box_ForwardValueChanged(app, event)
            ui1 = app.Theta2_Box_Forward.Value;
            app.theta2Slider.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);              
        end

        % Value changed function: Theta3_Box_Forward
        function Theta3_Box_ForwardValueChanged(app, event)
            ui3 = app.Theta3_Box_Forward.Value;
            app.theta3Slider.Value = ui3;
            th3_real_deg = ui3 + app.theta_offset_deg(3);
            app.new_theta(3) = deg2rad(th3_real_deg);     
        end

        % Value changed function: Theta4_Box_Forward
        function Theta4_Box_ForwardValueChanged(app, event)
            ui4 = app.Theta4_Box_Forward.Value;
            app.theta4Slider.Value = ui4;
            th4_real_deg = ui4 + app.theta_offset_deg(4);
            app.new_theta(4) = deg2rad(th4_real_deg);                          
        end

        % Value changed function: Pitch_input
        function Pitch_inputValueChanged(app, event)
            app.Pitch  = app.Pitch_input.Value;        
        end

        % Value changed function: WorkSpaceCheckBox
        function WorkSpaceCheckBoxValueChanged(app, event)
            app.Draw_Robot();            
        end

        % Value changed function: vmaxBox
        function vmaxBoxValueChanged(app, event)
            app.vmax = deg2rad(app.vmaxBox.Value);
            
        end

        % Value changed function: amaxBox
        function amaxBoxValueChanged(app, event)
            app.amax = deg2rad(app.amaxBox.Value);
            
        end

        % Button pushed function: PlanningButton
        function PlanningButtonPushed(app, event)
    %% ===== 1. CẤU HÌNH THAM SỐ ROBOT =====
    d1 = 77;                % Base Height
    L2 = 128;               % Link 2
    L3 = 24 + 124;          % Link 3 = 148mm
    L4 = 126 + 50;          % Link 4 = 176mm
    
    offsets = [0, 90, -90, 0]; 

    %% ===== 2. ĐỌC DỮ LIỆU =====
    px = app.Px_Inverse.Value;
    py = app.Py_Inverse.Value;
    pz = app.Pz_Inverse.Value;
    pitch_val = app.Pitch_input.Value;

    % Input Pitch -90 (thẳng đứng) -> Tính toán +90
    phi = deg2rad(-pitch_val);

    %% ===== 3. XỬ LÝ GÓC ĐẾ & BÁN KÍNH (LOGIC MỚI) =====
    
    % Tính góc đế thô
    theta1_raw = atan2(py, px);
    
    % Tính khoảng cách phẳng (Radius)
    pr = sqrt(px^2 + py^2);

    % --- LOGIC ƯU TIÊN GIỮ ĐẾ KHÔNG XOAY (BACKWARD REACH) ---
    % Nếu điểm đến nằm phía sau (góc đế > 90 hoặc < -90)
    % Thay vì quay đế 180 độ, ta giữ đế ở 0 và cho bán kính âm
    if abs(theta1_raw) > pi/2
        % Ép góc đế về phía trước (Xoay ngược 180 độ)
        % Ví dụ: đang là 180 -> thành 0
        if theta1_raw > 0
            theta1 = theta1_raw - pi;
        else
            theta1 = theta1_raw + pi;
        end
        
        % ĐẢO DẤU BÁN KÍNH (Quan trọng nhất)
        % Khi r âm, các công thức sau sẽ tự động hiểu là vươn ra sau
        pr = -pr; 
    else
        theta1 = theta1_raw;
    end

    %% ===== 4. TÍNH TOÁN IK =====

    % Tọa độ End-Effector so với khớp 2
    % Lưu ý: pr ở đây có thể là số ÂM (ví dụ -128)
    r_ee = pr;      
    z_ee = pz - d1;

    % Tọa độ Wrist Center (J4)
    r_wc = r_ee - L4 * cos(phi);
    z_wc = z_ee - L4 * sin(phi);

    % --- Tính Theta 3 ---
    numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
    denominator = 2 * L2 * L3;
    D = numerator / denominator;

    % Kẹp giá trị D
    if D > 1, D = 1; elseif D < -1, D = -1; end
    
    % Chọn Elbow Up (Dấu -)
    theta3_geom = -acos(D); 

    % --- Tính Theta 2 ---
    k1 = L2 + L3 * cos(theta3_geom);
    k2 = L3 * sin(theta3_geom);
    
    % Hàm atan2 xử lý rất tốt số âm: 
    % Nếu r_wc âm, nó sẽ tính ra góc tù (>90 độ) -> Robot ngả ra sau
    theta2_geom = atan2(z_wc, r_wc) - atan2(k2, k1);

    % --- Tính Theta 4 ---
    theta4_geom = phi - theta2_geom - theta3_geom;

    %% ===== 5. CẬP NHẬT KẾT QUẢ =====
    
    % Cập nhật biến vẽ
    app.theta(1) = theta1;
    app.theta(2) = theta2_geom;
    app.theta(3) = theta3_geom;
    app.theta(5) = theta4_geom;

    % Cập nhật Slider (Giá trị - Offset)
    app.Theta1_Box_Forward.Value = rad2deg(theta1) - offsets(1);
    app.theta1Slider.Value       = rad2deg(theta1) - offsets(1);

    app.Theta2_Box_Forward.Value = rad2deg(theta2_geom) - offsets(2);
    app.theta2Slider.Value       = rad2deg(theta2_geom) - offsets(2);

    app.Theta3_Box_Forward.Value = rad2deg(theta3_geom) - offsets(3);
    app.theta3Slider.Value       = rad2deg(theta3_geom) - offsets(3);

    app.Theta4_Box_Forward.Value = rad2deg(theta4_geom) - offsets(4);
    app.theta4Slider.Value       = rad2deg(theta4_geom) - offsets(4);

    % Vẽ lại robot
    app.old_theta(1) = app.new_theta(1);
    app.new_theta(1) = app.theta(1);
    app.old_theta(2) = app.new_theta(2);
    app.new_theta(2) = app.theta(2);
    app.old_theta(3) = app.new_theta(3);
    app.new_theta(3) = app.theta(3);
    app.old_theta(4) = app.new_theta(4);
    app.new_theta(4) = app.theta(5);
    app.LSPB_TrajectoryPlanning();
    
    %% Rời rạc hóa theta, phân tách thành 20 đoạn bằng nhau
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       % Rời rạc hóa theta1
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       % Rời rạc hóa theta2
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       % Rời rạc hóa theta3
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       % Rời rạc hóa theta3

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                drawnow limitrate;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator-X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-600 600];
            app.UIAxes.YLim = [-600 600];
            app.UIAxes.ZLim = [0 600];
            app.UIAxes.XTick = [-600 -500 -400 -300 -200 -100 0 100 200 300 400 500 600];
            app.UIAxes.XTickLabel = {'-600'; '-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxes.YTick = [-300 -200 -100 0 100 200 300];
            app.UIAxes.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.UIAxes.ZTick = [0 50 100 150 200 250 300 350 400];
            app.UIAxes.ZTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'};
            app.UIAxesTheta1 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta1, 'Theta1 (độ)')
            xlabel(app.UIAxesTheta1, 'Thời gian (s)')
            ylabel(app.UIAxesTheta1, 'P1(t) ')
            app.UIAxesTheta2 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta2, 'Theta2 (độ)')
            xlabel(app.UIAxesTheta2, 'Thời gian (s)')
            ylabel(app.UIAxesTheta2, 'P2(t) ')
            app.UIAxesTheta3 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta3, 'Theta3 (độ)')
            xlabel(app.UIAxesTheta3, 'Thời gian (s)')
            ylabel(app.UIAxesTheta3, 'P3(t) ')
            app.UIAxesTheta4 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta4, 'Theta4 (độ)')
            xlabel(app.UIAxesTheta4, 'Thời gian (s)')
            ylabel(app.UIAxesTheta4, 'P4(t) ')
            app.UIAxesVel1 = uiaxes(app.UIFigure);
            title(app.UIAxesVel1, 'Velocity1 (độ/s)')
            xlabel(app.UIAxesVel1, 'Thời gian (s)')
            ylabel(app.UIAxesVel1, 'v1(t)')
            app.UIAxesVel2 = uiaxes(app.UIFigure);
            title(app.UIAxesVel2, 'Velocity2 (độ/s)')
            xlabel(app.UIAxesVel2, 'Thời gian (s)')
            ylabel(app.UIAxesVel2, 'v2(t)')
            app.UIAxesVel3 = uiaxes(app.UIFigure);
            title(app.UIAxesVel3, 'Velocity3 (độ/s)')
            xlabel(app.UIAxesVel3, 'Thời gian (s)')
            ylabel(app.UIAxesVel3, 'v3(t)')
            app.UIAxesVel4 = uiaxes(app.UIFigure);
            title(app.UIAxesVel4, 'Velocity4(độ/s)')
            xlabel(app.UIAxesVel4, 'Thời gian (s)')
            ylabel(app.UIAxesVel4, 'v4(t)')
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.theta1SliderLabel = uilabel(app.INPUTPanel);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta1Slider = uislider(app.INPUTPanel);
            app.theta1Slider.Limits = [-180 180];
            app.theta1Slider.ValueChangedFcn = createCallbackFcn(app, @theta1SliderValueChanged, true);
            app.theta3SliderLabel = uilabel(app.INPUTPanel);
            app.theta3SliderLabel.Text = 'theta3';
            app.theta3Slider = uislider(app.INPUTPanel);
            app.theta3Slider.Limits = [-180 180];
            app.theta3Slider.ValueChangedFcn = createCallbackFcn(app, @theta3SliderValueChanged, true);
            app.theta4SliderLabel = uilabel(app.INPUTPanel);
            app.theta4SliderLabel.Text = 'theta4';
            app.theta4Slider = uislider(app.INPUTPanel);
            app.theta4Slider.Limits = [-180 180];
            app.theta4Slider.ValueChangedFcn = createCallbackFcn(app, @theta4SliderValueChanged, true);
            app.theta2SliderLabel = uilabel(app.INPUTPanel);
            app.theta2SliderLabel.Text = 'theta2';
            app.theta2Slider = uislider(app.INPUTPanel);
            app.theta2Slider.Limits = [-180 180];
            app.theta2Slider.ValueChangedFcn = createCallbackFcn(app, @theta2SliderValueChanged, true);
            app.ForwardButton = uibutton(app.INPUTPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.ResetButton = uibutton(app.INPUTPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Theta1_Box_Forward = uieditfield(app.INPUTPanel, 'numeric');
            app.Theta1_Box_Forward.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_ForwardValueChanged, true);
            app.Theta2_Box_Forward = uieditfield(app.INPUTPanel, 'numeric');
            app.Theta2_Box_Forward.ValueChangedFcn = createCallbackFcn(app, @Theta2_Box_ForwardValueChanged, true);
            app.Theta3_Box_Forward = uieditfield(app.INPUTPanel, 'numeric');
            app.Theta3_Box_Forward.ValueChangedFcn = createCallbackFcn(app, @Theta3_Box_ForwardValueChanged, true);
            app.Theta4_Box_Forward = uieditfield(app.INPUTPanel, 'numeric');
            app.Theta4_Box_Forward.ValueChangedFcn = createCallbackFcn(app, @Theta4_Box_ForwardValueChanged, true);
            app.ClearButton = uibutton(app.INPUTPanel, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.ClearButton.Text = 'Clear';
            app.OUTPUTPanel = uipanel(app.UIFigure);
            app.OUTPUTPanel.Title = 'OUTPUT';
            app.PxEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PyEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PzEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EndEffector = uieditfield(app.OUTPUTPanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_Angle = uieditfield(app.OUTPUTPanel, 'numeric');
            app.CoordinateaxesCheckBox = uicheckbox(app.OUTPUTPanel);
            app.CoordinateaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoordinateaxesCheckBoxValueChanged, true);
            app.CoordinateaxesCheckBox.Text = 'Click here to set the coordinate axes';
            app.WorkSpaceCheckBox = uicheckbox(app.OUTPUTPanel);
            app.WorkSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceCheckBoxValueChanged, true);
            app.WorkSpaceCheckBox.Text = 'WorkSpace';
            app.INVERSEPanel = uipanel(app.UIFigure);
            app.INVERSEPanel.Title = 'INVERSE';
            app.InverseButton = uibutton(app.INVERSEPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.PlanningButton = uibutton(app.INVERSEPanel, 'push');
            app.PlanningButton.ButtonPushedFcn = createCallbackFcn(app, @PlanningButtonPushed, true);
            app.PlanningButton.Text = 'Planning';
            app.PxEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_2.Text = 'Px';
            app.Px_Inverse = uieditfield(app.INVERSEPanel, 'numeric');
            app.Px_Inverse.ValueChangedFcn = createCallbackFcn(app, @Px_InverseValueChanged, true);
            app.Py_Inverse = uieditfield(app.INVERSEPanel, 'numeric');
            app.Py_Inverse.ValueChangedFcn = createCallbackFcn(app, @Py_InverseValueChanged, true);
            app.PyEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_2.Text = 'Py';
            app.Pz_Inverse = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pz_Inverse.ValueChangedFcn = createCallbackFcn(app, @Pz_InverseValueChanged, true);
            app.PzEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_2.Text = 'Pz';
            app.vmaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.vmaxBox.ValueChangedFcn = createCallbackFcn(app, @vmaxBoxValueChanged, true);
            app.v_maxEditFieldLabel = uilabel(app.INVERSEPanel);
            app.v_maxEditFieldLabel.Text = 'v_max';
            app.amaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.amaxBox.ValueChangedFcn = createCallbackFcn(app, @amaxBoxValueChanged, true);
            app.a_maxEditFieldLabel = uilabel(app.INVERSEPanel);
            app.a_maxEditFieldLabel.Text = 'a_max';
            app.Pitch_inputLabel = uilabel(app.INVERSEPanel);
            app.Pitch_inputLabel.Text = 'Pitch_input';
            app.Pitch_input = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pitch_input.ValueChangedFcn = createCallbackFcn(app, @Pitch_inputValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTL_KTRB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end